classdef BatterySimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InputTab                       matlab.ui.container.Tab
        BatterySimulationLabel         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        BatteryTypeDropDownLabel       matlab.ui.control.Label
        BatteryTypeDropDown            matlab.ui.control.DropDown
        ChargingPowerWEditFieldLabel   matlab.ui.control.Label
        ChargingPowerEditField         matlab.ui.control.NumericEditField
        LoadResistanceOhmEditFieldLabel  matlab.ui.control.Label
        LoadResistanceEditField        matlab.ui.control.NumericEditField
        CycleNumberEditFieldLabel      matlab.ui.control.Label
        CycleNumberEditField           matlab.ui.control.NumericEditField
        NEXTButton                     matlab.ui.control.Button
        SimulationTab                  matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        Panel_2                        matlab.ui.container.Panel
        SelectGraphDropDownLabel       matlab.ui.control.Label
        SelectGraphDropDown            matlab.ui.control.DropDown
        RunSimulationButton            matlab.ui.control.Button
        OrderatfirstCycleEditFieldLabel  matlab.ui.control.Label
        OrderatfirstCycleEditField     matlab.ui.control.NumericEditField
        orderatNthCycleEditFieldLabel  matlab.ui.control.Label
        orderatNthCycleEditField       matlab.ui.control.NumericEditField
        PerfectChargerEditFieldLabel   matlab.ui.control.Label
        PerfectChargerEditField        matlab.ui.control.NumericEditField
        BatteryHealthatnthcycleEditFieldLabel  matlab.ui.control.Label
        BatteryHealthatnthcycleEditField  matlab.ui.control.EditField
        TotaldischargetimeEditFieldLabel  matlab.ui.control.Label
        TotaldischargetimeEditField    matlab.ui.control.EditField
        ShowCurveForPerfectChargerButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        BatteryHealth
        Cycles
        DischargeTime
        ChargingCurveTime
        ChargingCurveSOC
        DischargingCurveTime
        DischargingCurveSOC
        ChargingTempTime
        ChargingTemp
        DischargingTempTime
        DischargingTemp
        OrderFirst
        OrderNth
        SelectedBatteryType
        SelectedChargingPower
        SelectedLoadResistance
        SelectedCycleNumber
        CapacitySurface3D
        PerfectChargerPower
        PerfectChargerCurveX
        PerfectChargerCurveY
    end
    
    methods (Access = private)
    
        function runSimulation(app)
            % === Parameters ===
            r0 = 0.1; alpha_r = 0.004; eta = 0.98; k_SEI = 2e-3;
            Ea = 25000; R_gas = 8.314; T_ref = 298;
        
            % Get battery type and set parameters
            if strcmpi(app.SelectedBatteryType, 'Li-Ion')
                rho = 2500; Cp = 900; Vc = 5; Vb = 3.7; C_nom = 5000;
                min_PCharge = 5; max_PCharge = 70;
                min_Rload = 0.27; max_Rload = 7.3;
            else
                rho = 2000; Cp = 750; Vc = 2.2; Vb = 1.6; C_nom = 2500;
                min_PCharge = 1.5; max_PCharge = 20;
                min_Rload = 0.22; max_Rload = 6.3;
            end
        
            % Use values from app properties
            PCharge = app.SelectedChargingPower;
            Rload = app.SelectedLoadResistance;
            n = app.SelectedCycleNumber;
        
            % === Main Simulation ===
            Ic = PCharge / Vc;
            Vgap = Vc - Vb;
            Pwaste = Ic^2 * r0 + Vgap * Ic;
            Prcv = eta * (PCharge - Pwaste);
            if Prcv <= 0
                uialert(app.UIFigure, 'Charging power too low or resistance too high, resulting in no usable power.', 'Simulation Error');
                return;
            end
        
            num_cycles = 15000;
            BH = 100 * ones(num_cycles, 1);
            t2 = zeros(num_cycles, 1);
            t3 = zeros(num_cycles, 1);
            TendC = zeros(num_cycles, 1);
            TendD = zeros(num_cycles, 1);
            Constant = zeros(num_cycles, 1);
            CMax = C_nom * ones(num_cycles, 1);
            QMax = C_nom * 3.6 * Vb * ones(num_cycles, 1);
            k = 2;
        
            while BH(k-1) >= 50 && k < num_cycles
                if k > 2
                    Tavg = 25 + ((TendC(k-2) + TendD(k-2))/2 - T_ref);
                else
                    Tavg = 25;
                end
                rT = r0 * (1 + alpha_r * (Tavg - 25));
                Ic = PCharge / Vc;
                Pwaste = Ic^2 * rT + Vgap * Ic;
                Prcv = eta * (PCharge - Pwaste);
                I_discharge = Vb / (Rload + rT);
                Pused = Vb * I_discharge;
        
                Prcv_safe = max(Prcv, 1e-10);
                Pused_safe = max(Pused, 1e-10);
        
                t2(k-1) = CMax(k-1) * Vb * 3.6 * 0.8 / Prcv_safe;
                t3(k-1) = CMax(k-1) * Vb * 3.6 * 0.8 / Pused_safe;
        
                min_time = 10;
                if t2(k-1) <= min_time, t2(k-1) = min_time; end
                if t3(k-1) <= min_time, t3(k-1) = min_time; end
        
                PTC = Pwaste;
                PTD = Vb^2 * rT / Rload^2;
                TendC(k-1) = PTC * t2(k-1)^2 / (2 * rho * Cp) + T_ref;
                TendD(k-1) = PTD * t3(k-1)^2 / (2 * rho * Cp) + T_ref;
                Constant(k-1) = exp(-Ea * (1/TendC(k-1) - 1/T_ref) / R_gas) * sqrt(t2(k-1)) + ...
                                exp(-Ea * (1/TendD(k-1) - 1/T_ref) / R_gas) * sqrt(t3(k-1));
                kT = k_SEI * Constant(k-1);
                dSEI = kT;
                CMax(k) = max(CMax(k-1) - 4 * dSEI, 0);
                BH(k) = CMax(k) / (C_nom / 100);
                QMax(k) = CMax(k) * 3.6 * Vb;
                k = k + 1;
            end
        
            if n >= k || n <= 1
                n = k - 1;
            end
        
            ord1 = Constant(1) / (t2(1) + t3(1));
            ordn = Constant(n) / (t2(n) + t3(n));
        
            % Charging curve
            safe_QMax = max(QMax(n), 1);
            safe_Prcv = max(Prcv, 1);
            safe_Pused = max(Pused, 1);
            tcharging = 1:round(t2(n));
            Qtc = safe_QMax * 0.1 + safe_Prcv * tcharging;
            Qtc(Qtc > safe_QMax) = safe_QMax;
        
            % Discharging curve
            tdischarging = 1:round(t3(n));
            Qtd = safe_QMax * 0.9 - safe_Pused * tdischarging;
            Qtd(Qtd < 0) = 0;
        
            % Temperature rise during charging
            TCC = Pwaste * (tcharging).^2 / (2 * rho * Cp) + 25;
        
            % Temperature rise during discharging
            TDD = (Vb^2 * r0 / Rload^2) * (tdischarging).^2 / (2 * rho * Cp) + 25;
        
            % 3D plots
            cycles = 1:k-1;
            valid_idx = (CMax(1:k-1) > 0);
            cycles_plot = cycles(valid_idx);
            TendC_plot = TendC(valid_idx);
            TendD_plot = TendD(valid_idx);
            CMax_plot = CMax(valid_idx);
        
            % Store results in app properties
            app.BatteryHealth = BH(1:k-1);
            app.Cycles = cycles;
            app.DischargeTime = t3(1:k-1)/60;
            app.ChargingCurveTime = tcharging/60;
            app.ChargingCurveSOC = Qtc*100/safe_QMax;
            app.DischargingCurveTime = tdischarging/60;
            app.DischargingCurveSOC = Qtd*100/safe_QMax;
            app.ChargingTempTime = tcharging/60;
            app.ChargingTemp = TCC;
            app.DischargingTempTime = tdischarging/60;
            app.DischargingTemp = TDD;
            app.OrderFirst = ord1;
            app.OrderNth = ordn;
        
            % Update numeric fields for order values
            app.OrderatfirstCycleEditField.Value = ord1;
            app.orderatNthCycleEditField.Value = ordn;
        % Calculate total discharge time in hours
            total_discharge_time = sum(t3(1:k-1)) / 3600;
            
            % Battery health at nth cycle (as percentage)
            battery_health_n = BH(n);
            
            % Update the numeric/text fields in the app
            app.BatteryHealthatnthcycleEditField.Value = sprintf('%.2f', battery_health_n);
            app.TotaldischargetimeEditField.Value = sprintf('%.2f', total_discharge_time);
            % Show default plot
            app.SelectGraphDropDown.Value = 'Battery Health';
            SelectGraphDropDownValueChanged(app, []);
        end
        function calculatePerfectCharger(app)
    % Use the same parameters as your main simulation
    if strcmpi(app.SelectedBatteryType, 'Li-Ion')
        rho = 2500; Cp = 900; Vc = 5; Vb = 3.7; C_nom = 5000;
        r = 0.1;
        PChargeSweep = 1:1:70;
    else
        rho = 2000; Cp = 750; Vc = 2.2; Vb = 1.6; C_nom = 2500;
        r = 0.1;
        PChargeSweep = 1:1:20;
    end
    Rload = app.SelectedLoadResistance;
    T_ref = 298; k_SEI = 2e-3; Ea = 25000; R_gas = 8.314;
    total_discharge_time = zeros(length(PChargeSweep), 1);

    for i = 1:length(PChargeSweep)
        Ic = PChargeSweep(i) / Vc;
        Pwaste = Ic^2 * r + (Vc - Vb) * Ic;
        Prcv = PChargeSweep(i) - Pwaste;

        I_discharge = Vb / (Rload + r);
        Pused = Vb * I_discharge;

        num_cycles = 500;
        BH = 100 * ones(num_cycles, 1);
        t2 = zeros(num_cycles, 1);
        t3 = zeros(num_cycles, 1);
        TendC = zeros(num_cycles, 1);
        TendD = zeros(num_cycles, 1);
        Constant = zeros(num_cycles, 1);
        CMax = C_nom * ones(num_cycles, 1);
        QMax = C_nom * 3.6 * Vb * ones(num_cycles, 1);

        PTC = Pwaste;
        PTD = Vb^2 * r / Rload^2;

        k = 2;
        while BH(k-1) >= 50 && k < num_cycles
            t2(k-1) = CMax(k-1) * Vb * 3.6 * 0.8 / Prcv;
            t3(k-1) = CMax(k-1) * Vb * 3.6 * 0.8 / Pused;

            TendC(k-1) = PTC * t2(k-1)^2 / (2 * rho * Cp) + T_ref;
            TendD(k-1) = PTD * t3(k-1)^2 / (2 * rho * Cp) + T_ref;

            Constant(k-1) = exp(-Ea * (1/TendC(k-1) - 1/T_ref) / R_gas) * sqrt(t2(k-1)) + ...
                            exp(-Ea * (1/TendD(k-1) - 1/T_ref) / R_gas) * sqrt(t3(k-1));

            kT = k_SEI * Constant(k-1);
            dSEI = kT;

            CMax(k) = CMax(k-1) - 4 * dSEI;
            BH(k) = CMax(k) / (C_nom / 100);
            QMax(k) = CMax(k) * 3.6 * Vb;

            k = k + 1;
        end

        total_discharge_time(i) = sum(t3(1:k-1)) / 3600; % in hours
    end

    % Find the perfect charger power (max discharge time)
    [~, idx] = max(total_discharge_time);
    app.PerfectChargerPower = PChargeSweep(idx);
    app.PerfectChargerCurveX = PChargeSweep;
    app.PerfectChargerCurveY = total_discharge_time;
    end
        
    function plot3DCapacitySurface(app)
        % Set battery parameters based on selected type
        if strcmpi(app.SelectedBatteryType, 'Li-Ion')
            rho = 2500; Cp = 900; Vc = 5; Vb = 3.7; C_nom = 5000;
            min_PCharge = 5; max_PCharge = 70;
        else
            rho = 2000; Cp = 750; Vc = 2.2; Vb = 1.6; C_nom = 2500;
            min_PCharge = 1.5; max_PCharge = 20;
        end
        r0 = 0.1; eta = 0.98; k_SEI = 2e-3;
        Ea = 25000; R_gas = 8.314; T_ref = 298;
        Rload = app.SelectedLoadResistance;
    
        PCharge_sweep = linspace(min_PCharge, max_PCharge, 10); % 10 charging powers
        num_cycles_3d = 200; % Number of cycles to simulate for each charging power
        CMax_matrix = zeros(num_cycles_3d, length(PCharge_sweep)); % Capacity storage
    
        for j = 1:length(PCharge_sweep)
            PCharge = PCharge_sweep(j);
            Ic = PCharge / Vc;
            Vgap = Vc - Vb;
            Pwaste = Ic^2 * r0 + Vgap * Ic;
            Prcv = eta * (PCharge - Pwaste);
            if Prcv <= 0
                CMax_matrix(:,j) = NaN; % Skip invalid cases
                continue;
            end
            CMax = C_nom * ones(num_cycles_3d, 1);
            k = 2;
            while k <= num_cycles_3d && CMax(k-1) > 0
                rT = r0; % For simplicity, ignore temp rise in this sweep
                I_discharge = Vb / (Rload + rT);
                Pused = Vb * I_discharge;
                Prcv_safe = max(Prcv, 1e-10);
                Pused_safe = max(Pused, 1e-10);
                t2 = CMax(k-1) * Vb * 3.6 * 0.8 / Prcv_safe;
                t3 = CMax(k-1) * Vb * 3.6 * 0.8 / Pused_safe;
                PTC = Pwaste;
                PTD = Vb^2 * rT / Rload^2;
                TendC = PTC * t2^2 / (2 * rho * Cp) + T_ref;
                TendD = PTD * t3^2 / (2 * rho * Cp) + T_ref;
                Constant = exp(-Ea * (1/TendC - 1/T_ref) / R_gas) * sqrt(t2) + ...
                           exp(-Ea * (1/TendD - 1/T_ref) / R_gas) * sqrt(t3);
                kT = k_SEI * Constant;
                dSEI = kT;
                CMax(k) = max(CMax(k-1) - 4 * dSEI, 0);
                k = k + 1;
            end
            CMax_matrix(:,j) = CMax;
        end
    
        [Cycles, PCharges] = meshgrid(1:num_cycles_3d, PCharge_sweep);
    
        % Plot on UIAxes2
        cla(app.UIAxes2);
        surf(app.UIAxes2, Cycles', PCharges', CMax_matrix, 'EdgeColor', 'none');
        xlabel(app.UIAxes2, 'Cycle Number');
        ylabel(app.UIAxes2, 'Charging Power (W)');
        zlabel(app.UIAxes2, 'Capacity (mAh)');
        title(app.UIAxes2, 'Capacity vs Cycle Number and Charging Power');
        colorbar(app.UIAxes2);
        view(app.UIAxes2, 135, 30);
        grid(app.UIAxes2, 'on');
    end


end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('battery100.jpeg', 'Parent', app.UIAxes);
            % Hide axes ticks and labels
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.XColor = 'none';
            app.UIAxes.YColor = 'none';
            app.UIAxes.Box = 'off';
        
            
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            % Read user input
            batteryType = app.BatteryTypeDropDown.Value;
            chargingPower = app.ChargingPowerEditField.Value;
            loadResistance = app.LoadResistanceEditField.Value;
            cycleNumber = app.CycleNumberEditField.Value;
        
            % Validate input
            valid = true;
            msg = "";
        
            % Example validation (adjust ranges as needed)
            if strcmpi(batteryType, 'Li-Ion')
                minP = 5; maxP = 70; minR = 0.27; maxR = 7.3;
            else
                minP = 1.5; maxP = 20; minR = 0.22; maxR = 6.3;
            end
        
            if chargingPower < minP || chargingPower > maxP
                valid = false;
                msg = sprintf('Charging power must be between %.1f and %.1f W.', minP, maxP);
            elseif loadResistance < minR || loadResistance > maxR
                valid = false;
                msg = sprintf('Load resistance must be between %.2f and %.2f Ohm.', minR, maxR);
            elseif cycleNumber < 1 || cycleNumber > 700
                valid = false;
                msg = 'Cycle number must be between 1 and 700.';
            end
        
            if ~valid
                uialert(app.UIFigure, msg, 'Input Error');
                return;
            end
        
            % Store values in app properties for later use
            app.SelectedBatteryType = batteryType;
            app.SelectedChargingPower = chargingPower;
            app.SelectedLoadResistance = loadResistance;
            app.SelectedCycleNumber = cycleNumber;
            app.TabGroup.SelectedTab = app.SimulationTab;
            calculatePerfectCharger(app);
            app.PerfectChargerEditField.Value = app.PerfectChargerPower;
        
            % (Optional) Switch to the next panel/tab or update the UI
            % Example: app.TabGroup.SelectedTab = app.SimulationTab;
        
            % (Optional) Display a confirmation message
            % uialert(app.UIFigure, 'Inputs accepted! Proceeding to simulation.', 'Success');
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            runSimulation(app);
        end

        % Value changed function: SelectGraphDropDown
        function SelectGraphDropDownValueChanged(app, event)
            selectedPlot = app.SelectGraphDropDown.Value;
            cla(app.UIAxes2); % Clear axes
            
            switch selectedPlot
                case 'Battery Health'
                    plot(app.UIAxes2, app.Cycles, app.BatteryHealth, 'LineWidth', 2);
                    xlabel(app.UIAxes2, 'Cycle Number');
                    ylabel(app.UIAxes2, 'Battery Health (%)');
                    title(app.UIAxes2, 'Battery Health over Cycles');
                    grid(app.UIAxes2, 'on');
                case 'Discharge Time'
                    plot(app.UIAxes2, app.Cycles, app.DischargeTime, 'LineWidth', 2);
                    xlabel(app.UIAxes2, 'Cycle Number');
                    ylabel(app.UIAxes2, 'Discharge Time (min)');
                    title(app.UIAxes2, 'Discharge Time per Cycle');
                    grid(app.UIAxes2, 'on');
                case 'Charging Curve'
                    plot(app.UIAxes2, app.ChargingCurveTime, app.ChargingCurveSOC, 'b', 'LineWidth', 2);
                    xlabel(app.UIAxes2, 'Time (min)');
                    ylabel(app.UIAxes2, 'State of Charge (%)');
                    title(app.UIAxes2, 'Charging Curve');
                    grid(app.UIAxes2, 'on');
                case 'Discharging Curve'
                    plot(app.UIAxes2, app.DischargingCurveTime, app.DischargingCurveSOC, 'r', 'LineWidth', 2);
                    xlabel(app.UIAxes2, 'Time (min)');
                    ylabel(app.UIAxes2, 'State of Charge (%)');
                    title(app.UIAxes2, 'Discharging Curve');
                    grid(app.UIAxes2, 'on');
                case 'Charging Temp'
                    plot(app.UIAxes2, app.ChargingTempTime, app.ChargingTemp, 'm', 'LineWidth', 2);
                    xlabel(app.UIAxes2, 'Time (min)');
                    ylabel(app.UIAxes2, 'Temperature (°C)');
                    title(app.UIAxes2, 'Temperature Rise during Charging');
                    grid(app.UIAxes2, 'on');
                case 'Discharging Temp'
                    plot(app.UIAxes2, app.DischargingTempTime, app.DischargingTemp, 'k', 'LineWidth', 2);
                    xlabel(app.UIAxes2, 'Time (min)');
                    ylabel(app.UIAxes2, 'Temperature (°C)');
                    title(app.UIAxes2, 'Temperature Rise during Discharging');
                    grid(app.UIAxes2, 'on');
                case '3D Capacity Surface'
                    plot3DCapacitySurface(app);
                
            end
        end

        % Value changed function: BatteryTypeDropDown
        function BatteryTypeDropDownValueChanged(app, event)
            value = app.BatteryTypeDropDown.Value;
            
        end

        % Value changed function: PerfectChargerEditField
        function PerfectChargerEditFieldValueChanged(app, event)
            value = app.PerfectChargerEditField.Value;
            
        end

        % Button pushed function: ShowCurveForPerfectChargerButton
        function ShowCurveForPerfectChargerButtonPushed(app, event)
            % Plot the perfect charger curve
            cla(app.UIAxes2);
            plot(app.UIAxes2, app.PerfectChargerCurveX, app.PerfectChargerCurveY, 'b-o', 'LineWidth', 2);
            xlabel(app.UIAxes2, 'Charging Power (W)');
            ylabel(app.UIAxes2, 'Total Discharge Time (h)');
            title(app.UIAxes2, 'Discharge Duration vs Charging Power');
            grid(app.UIAxes2, 'on');
            hold(app.UIAxes2, 'on');
            % Highlight the perfect charger point
            [~, idx] = max(app.PerfectChargerCurveY);
            plot(app.UIAxes2, app.PerfectChargerCurveX(idx), app.PerfectChargerCurveY(idx), ...
                'rp', 'MarkerSize', 14, 'MarkerFaceColor', 'r');
            % Optionally, add a text label
            text(app.UIAxes2, app.PerfectChargerCurveX(idx), app.PerfectChargerCurveY(idx), ...
                sprintf(' Perfect: %.2f W', app.PerfectChargerCurveX(idx)), ...
                'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left', 'FontSize', 12, 'Color', 'r');
            hold(app.UIAxes2, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input Tab';
            app.BatterySimulationLabel = uilabel(app.InputTab);
            app.BatterySimulationLabel.Text = 'Battery Simulation';
            app.UIAxes = uiaxes(app.InputTab);
            title(app.UIAxes, {'Battery'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.InputTab);
            app.BatteryTypeDropDownLabel = uilabel(app.Panel);
            app.BatteryTypeDropDownLabel.Text = 'Battery Type';
            app.BatteryTypeDropDown = uidropdown(app.Panel);
            app.BatteryTypeDropDown.Items = {'Li-Ion', 'Ni-metal hydride (Ni-MH)'};
            app.BatteryTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @BatteryTypeDropDownValueChanged, true);
            app.BatteryTypeDropDown.Value = 'Li-Ion';
            app.ChargingPowerWEditFieldLabel = uilabel(app.Panel);
            app.ChargingPowerWEditFieldLabel.Text = 'Charging Power (W)';
            app.ChargingPowerEditField = uieditfield(app.Panel, 'numeric');
            app.LoadResistanceOhmEditFieldLabel = uilabel(app.Panel);
            app.LoadResistanceOhmEditFieldLabel.Text = 'Load Resistance (Ohm)';
            app.LoadResistanceEditField = uieditfield(app.Panel, 'numeric');
            app.CycleNumberEditFieldLabel = uilabel(app.Panel);
            app.CycleNumberEditFieldLabel.Text = 'Cycle Number';
            app.CycleNumberEditField = uieditfield(app.Panel, 'numeric');
            app.NEXTButton = uibutton(app.InputTab, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation Tab';
            app.UIAxes2 = uiaxes(app.SimulationTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Panel_2 = uipanel(app.SimulationTab);
            app.SelectGraphDropDownLabel = uilabel(app.Panel_2);
            app.SelectGraphDropDownLabel.Text = 'Select Graph';
            app.SelectGraphDropDown = uidropdown(app.Panel_2);
            app.SelectGraphDropDown.Items = {'Battery Health', 'Discharge Time', 'Charging Curve', 'Discharging Curve', 'Charging Temp', 'Discharging Temp', '3D Capacity Surface'};
            app.SelectGraphDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectGraphDropDownValueChanged, true);
            app.SelectGraphDropDown.Value = 'Battery Health';
            app.RunSimulationButton = uibutton(app.Panel_2, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.OrderatfirstCycleEditFieldLabel = uilabel(app.Panel_2);
            app.OrderatfirstCycleEditFieldLabel.Text = 'Order at first Cycle';
            app.OrderatfirstCycleEditField = uieditfield(app.Panel_2, 'numeric');
            app.OrderatfirstCycleEditField.Editable = 'off';
            app.orderatNthCycleEditFieldLabel = uilabel(app.Panel_2);
            app.orderatNthCycleEditFieldLabel.Text = 'order at Nth Cycle';
            app.orderatNthCycleEditField = uieditfield(app.Panel_2, 'numeric');
            app.orderatNthCycleEditField.Editable = 'off';
            app.PerfectChargerEditFieldLabel = uilabel(app.Panel_2);
            app.PerfectChargerEditFieldLabel.Text = 'Perfect Charger';
            app.PerfectChargerEditField = uieditfield(app.Panel_2, 'numeric');
            app.PerfectChargerEditField.ValueChangedFcn = createCallbackFcn(app, @PerfectChargerEditFieldValueChanged, true);
            app.PerfectChargerEditField.Editable = 'off';
            app.BatteryHealthatnthcycleEditFieldLabel = uilabel(app.Panel_2);
            app.BatteryHealthatnthcycleEditFieldLabel.Text = 'Battery Health at nth cycle';
            app.BatteryHealthatnthcycleEditField = uieditfield(app.Panel_2, 'text');
            app.TotaldischargetimeEditFieldLabel = uilabel(app.Panel_2);
            app.TotaldischargetimeEditFieldLabel.Text = 'Total discharge time';
            app.TotaldischargetimeEditField = uieditfield(app.Panel_2, 'text');
            app.ShowCurveForPerfectChargerButton = uibutton(app.Panel_2, 'push');
            app.ShowCurveForPerfectChargerButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCurveForPerfectChargerButtonPushed, true);
            app.ShowCurveForPerfectChargerButton.Text = 'Show Curve For Perfect Charger';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatterySimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end